classdef PALEOSTRIPv1_0_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PALEOSTRIP                    matlab.ui.Figure
        text1                         matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        BACKTRACKINGTab               matlab.ui.container.Tab
        TabGroup                      matlab.ui.container.TabGroup
        IsostasyTab                   matlab.ui.container.Tab
        IsoButtonGroup                matlab.ui.container.ButtonGroup
        IsostasyButton                matlab.ui.control.RadioButton
        NoisostasyButton              matlab.ui.control.RadioButton
        text_flexural_params          matlab.ui.control.Label
        text_EET_selection            matlab.ui.control.Label
        text_Young_modulus            matlab.ui.control.Label
        text_Poisson                  matlab.ui.control.Label
        poisson_ratio_val             matlab.ui.control.EditField
        young_modulus_val             matlab.ui.control.EditField
        EET_val                       matlab.ui.control.EditField
        pushbutton_file_EET           matlab.ui.control.Button
        EETButton                     matlab.ui.container.ButtonGroup
        EET_ConstantButton            matlab.ui.control.RadioButton
        EET_CustomButton              matlab.ui.control.RadioButton
        isomenu                       matlab.ui.control.DropDown
        ThermalsubsidenceTab          matlab.ui.container.Tab
        TSButtonGroup                 matlab.ui.container.ButtonGroup
        ThermalsubsidenceButton       matlab.ui.control.RadioButton
        NothermalsubsButton           matlab.ui.control.RadioButton
        StrechingFactorButtonGroup    matlab.ui.container.ButtonGroup
        UniformBetaB1Button           matlab.ui.control.RadioButton
        CustomBetasectionormapButton  matlab.ui.control.RadioButton
        pushbutton_custom_Beta        matlab.ui.control.Button
        BetaInterpolation             matlab.ui.control.DropDown
        InterpolatedBetaB1B2Button    matlab.ui.control.RadioButton
        Beta1EditFieldLabel           matlab.ui.control.Label
        Beta1EditField                matlab.ui.control.NumericEditField
        Beta2Label                    matlab.ui.control.Label
        Beta2EditField                matlab.ui.control.NumericEditField
        CoeffEditFieldLabel           matlab.ui.control.Label
        CoeffEditField                matlab.ui.control.NumericEditField
        EndofriftingMaEditFieldLabel  matlab.ui.control.Label
        EndofriftingMaEditField       matlab.ui.control.NumericEditField
        InitiallithothicknesskmEditFieldLabel  matlab.ui.control.Label
        InitiallithothicknesskmEditField  matlab.ui.control.NumericEditField
        InitialcrustalthicknesskmEditFieldLabel  matlab.ui.control.Label
        InitialcrustalthicknesskmEditField  matlab.ui.control.NumericEditField
        Thermalexpansioncoeff1CEditFieldLabel  matlab.ui.control.Label
        Thermalexpansioncoeff1CEditField  matlab.ui.control.NumericEditField
        Thermaldiffusionoflithom2sEditFieldLabel  matlab.ui.control.Label
        Thermaldiffusionoflithom2sEditField  matlab.ui.control.NumericEditField
        SeaLevelTab                   matlab.ui.container.Tab
        SLButtonGroup                 matlab.ui.container.ButtonGroup
        SealevelchangesButton         matlab.ui.control.RadioButton
        NosealevelchangesButton       matlab.ui.control.RadioButton
        SL_method_buttongroup         matlab.ui.container.ButtonGroup
        ds_val                        matlab.ui.control.EditField
        pushbutton_customsl           matlab.ui.control.Button
        radiobutton_ds                matlab.ui.control.RadioButton
        radiobutton_haq               matlab.ui.control.RadioButton
        radiobutton_miller            matlab.ui.control.RadioButton
        UserTimeseriesButton          matlab.ui.control.RadioButton
        timeseriesLabel               matlab.ui.control.Label
        UserSectionMapButton          matlab.ui.control.RadioButton
        timeseriesLabel_2             matlab.ui.control.Label
        DynamictopographyTab          matlab.ui.container.Tab
        DynTopoButtonGroup            matlab.ui.container.ButtonGroup
        DynamictopoButton             matlab.ui.control.RadioButton
        NodynamictopoButton           matlab.ui.control.RadioButton
        uibuttongroup18_2             matlab.ui.container.ButtonGroup
        radiobutton_dtopo             matlab.ui.control.RadioButton
        radiobutton_2D3D              matlab.ui.control.RadioButton
        Openfile_2D3D                 matlab.ui.control.Button
        dtopo_val                     matlab.ui.control.NumericEditField
        radiobutton_1D                matlab.ui.control.RadioButton
        Openfile_1D                   matlab.ui.control.Button
        CorrectionrelativetopresentLabel  matlab.ui.control.Label
        TpushbuttonProcess            matlab.ui.control.Button
        ImportData_uipanel            matlab.ui.container.Panel
        TuitableParams                matlab.ui.control.Table
        TpushbuttonImportData         matlab.ui.control.Button
        TpushbuttonSaveParameters     matlab.ui.control.Button
        Grid_Selection                matlab.ui.container.ButtonGroup
        GridButtonGroup               matlab.ui.container.ButtonGroup
        Button_1D                     matlab.ui.control.RadioButton
        Button_2D                     matlab.ui.control.RadioButton
        Button_3D                     matlab.ui.control.RadioButton
        text_runid                    matlab.ui.control.Label
        edit_runid                    matlab.ui.control.EditField
        text24                        matlab.ui.control.Label
        text26                        matlab.ui.control.Label
        text25                        matlab.ui.control.Label
        Rhom_val                      matlab.ui.control.EditField
        Rhoc_val                      matlab.ui.control.EditField
        Rhow_val                      matlab.ui.control.EditField
        CLOSEButton                   matlab.ui.control.Button
        PLOTSAVERESULTSTab            matlab.ui.container.Tab
        Plot_results_uipanel          matlab.ui.container.Panel
        Plot_uipanel                  matlab.ui.container.Panel
        TpushbuttonPlotResults        matlab.ui.control.Button
        TpushbuttonContour            matlab.ui.control.Button
        text_plotvar                  matlab.ui.control.Label
        TpushbuttonSaveResults        matlab.ui.control.Button
        text_savevar                  matlab.ui.control.Label
        plotresults                   matlab.ui.control.DropDown
        maps2D3D_selection            matlab.ui.container.ButtonGroup
        Button_2Dmaps                 matlab.ui.control.RadioButton
        Button_3Dmaps                 matlab.ui.control.RadioButton
        saveresults                   matlab.ui.control.DropDown
        MinEditFieldLabel             matlab.ui.control.Label
        MinEditField                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.NumericEditField
        ColorbarButtonGroup           matlab.ui.container.ButtonGroup
        AutomaticlevelsButton         matlab.ui.control.RadioButton
        CustomlevelsButton            matlab.ui.control.RadioButton
        PaletteLabel                  matlab.ui.control.Label
        PaletteMenu2                  matlab.ui.control.DropDown
        FlipUD2_Button                matlab.ui.container.ButtonGroup
        FlipUD2_No                    matlab.ui.control.RadioButton
        FlipUD2_Yes                   matlab.ui.control.RadioButton
        FlipUDLabel_2                 matlab.ui.control.Label
        Extract_profile_uipanel       matlab.ui.container.Panel
        Extract_coord_Selection       matlab.ui.container.ButtonGroup
        radiobutton_coord_file        matlab.ui.control.RadioButton
        radiobutton_coord_table       matlab.ui.control.RadioButton
        pushbutton_load_profile       matlab.ui.control.Button
        TuitablePoints                matlab.ui.control.Table
        text47                        matlab.ui.control.Label
        text_extract                  matlab.ui.control.Label
        Tpushbutton_Extract           matlab.ui.control.Button
        Tpushbutton_Plot_extracted    matlab.ui.control.Button
        edit_profile_name             matlab.ui.control.EditField
        Tpushbutton_save_extracted    matlab.ui.control.Button
        ExtractVar                    matlab.ui.control.DropDown
        INITIALDATAPanel              matlab.ui.container.Panel
        InitialData                   matlab.ui.control.DropDown
        PlotvariablesLabel            matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SavevariablesLabel            matlab.ui.control.Label
        SaveInitButton                matlab.ui.control.Button
        MapviewButtonGroup            matlab.ui.container.ButtonGroup
        plainmaps2DButton             matlab.ui.control.RadioButton
        real3DmapsButton              matlab.ui.control.RadioButton
        ColorbarButtonGroup_2         matlab.ui.container.ButtonGroup
        AutomaticlevelsButton_2       matlab.ui.control.RadioButton
        CustomlevelsButton_2          matlab.ui.control.RadioButton
        MinEditField_2Label           matlab.ui.control.Label
        MinEditField_2                matlab.ui.control.NumericEditField
        MaxEditField_2Label           matlab.ui.control.Label
        MaxEditField_2                matlab.ui.control.NumericEditField
        PaletteLabel_2                matlab.ui.control.Label
        PaletteMenu                   matlab.ui.control.DropDown
        FlipUD_Button                 matlab.ui.container.ButtonGroup
        FlipUD_No                     matlab.ui.control.RadioButton
        FlipUD_Yes                    matlab.ui.control.RadioButton
        FlipUDLabel                   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

%-----------------------------------------------------------------------------------
% Copyright (C) 2020 PALEOSTRIP Authors
% 
% This file is part of PALEOSTRIP.
% 
% PALEOSTRIP is free software; you can redistribute it and/or modify it under the
% terms of the GNU General Public License as published by the Free Software
% Foundation; either version 3 of the License, or (at your option) any later
% version.
% 
% PALEOSTRIP is distributed in the hope that it will be useful, but WITHOUT ANY
% WARRANTY; without even the implied warranty of MERCHANTABILITY or FITNESS
% FOR A PARTICULAR PURPOSE.  See the GNU General Public License for more
% details.
% 
% You should have received a copy of the GNU General Public License
% along with PALEOSTRIP; if not, write to the Free Software
% Foundation, Inc., 51 Franklin St, Fifth Floor, Boston, MA  02110-1301  USA
%-----------------------------------------------------------------------------------
    
    methods (Access = private)
        function [out, outputstring] = getParamsFromTable(app)%(app, handles)
            % get parameters from the table
            
            out=[];
            outputstring=[];
            Data=get(app.TuitableParams,'Data');
            
            for ii=1:size(Data,1)-1
                if ~isnan(Data{ii,1})
                    params(ii).Surpor= Data{ii,1};
                else
                    outputstring=['Porosity of layer ' num2str(ii) ' is NaN.'];
                    return
                end
                if ~isnan(Data{ii,2})
                    params(ii).C= Data{ii,2};
                else
                    outputstring=['Depth coefficient of layer ' num2str(ii) ' is NaN.'];
                    return
                end
                if ~isnan(Data{ii,3})
                    params(ii).Rhos= Data{ii,3};
                else
                    outputstring=['Density of layer ' num2str(ii) ' is NaN.'];
                    return
                end
                if ~isnan(Data{ii,4})
                    params(ii).AgeBase= Data{ii,4};
                else
                    outputstring=['Age of layer ' num2str(ii) ' is NaN.'];
                    return
                end
                params(ii).Name=Data{ii,5};
            end
            
            out=params;
        end
        
        function logfile(app,gui_str,gui_val,param_file)
            
            fid = fopen(param_file, 'a'); %fopen(fullfile(tempdir, 'YourLogFile.txt'), 'a');
            if fid == -1
                error('Cannot open log file.');
            end
            opt_string=[gui_str,'=',gui_val];
            fprintf(fid, '%s\n', opt_string);
            fclose(fid);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PALEOSTRIP_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to PALEOSTRIP (see VARARGIN)
            %
            % Choose default command line output for PALEOSTRIP
            %            app.output = hObject;
            
            % Update handles structure
            %            guidata(hObject, handles);
            % UIWAIT makes PALEOSTRIP wait for user response (see UIRESUME)
            uiwait(app.PALEOSTRIP);
            
            % set to zero the profile extrema
            DataPoints={};
            DataPoints{1,1}=0;DataPoints{2,1}=0;
            DataPoints{1,2}=0;DataPoints{2,2}=0;
            

        end

        % Button pushed function: TpushbuttonImportData
        function TpushbuttonImportData_Callback(app, event)
            % hObject    handle to TpushbuttonImportData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                addpath(genpath('./auxiliary'));
                addpath(genpath('./auxiliary/read_data'));
            
            emptyparameterfile=false;


            foldername = uigetdir(cd,'Select the folder where the *.par file is');
            
            
            if isnumeric(foldername) % cancel button
                return
            end
            
            
            pfiles=dir(fullfile(foldername, '*.par'));
            
            
            if length(pfiles)~=1
                errordlg('The folder must contain exactly one parameter file.', 'Error', 'modal');
                return
            end
            paramfile=pfiles(1).name;
            
            
            params=read_params(fullfile(foldername,paramfile));
            
            if isempty(params)
                
                ButtonName = questdlg('The parameter file is empty or has wrong format. You will need to enter the parameters manually. Do you wish to continue?', ...
                    'Empty or unreadable parameter file', ...
                    'Yes', 'No', 'Yes');
                switch ButtonName
                    case 'Yes'
                        emptyparameterfile=true;
                    case 'No'
                        return
                end % switch
                
            end
            
            
            % get the data
            [~,name] = fileparts(pfiles(1).name);
            dfiles=dir(fullfile(foldername, [name '*.dat']));
            
            
            if ( length(dfiles)~= (length(params)+1) ) && ~emptyparameterfile
                errordlg('The folder must contain a .dat file per layer and a .dat file for the bathymetry.', 'Error', 'modal');
                return
            end
            
            set(app.TpushbuttonProcess,'enable','off');
            set(app.TpushbuttonContour,'enable','off');
            set(app.TpushbuttonSaveResults,'enable','off');
            set(app.TpushbuttonPlotResults,'enable','off');
            set(app.Tpushbutton_Extract,'enable','off')
            set(app.Tpushbutton_Plot_extracted,'enable','off')
            set(app.Tpushbutton_save_extracted,'enable','off')
            
            setappdata(app.PALEOSTRIP,'M',[]);
            setappdata(app.PALEOSTRIP,'profiles',[]);
            setappdata(app.PALEOSTRIP,'isopacks',[]);
            setappdata(app.PALEOSTRIP,'density',[]);
            setappdata(app.PALEOSTRIP,'porosity',[]);
            setappdata(app.PALEOSTRIP,'tsubs',[]);
            setappdata(app.PALEOSTRIP,'isostasy',[]);
            setappdata(app.PALEOSTRIP,'betaF',[]);
            setappdata(app.PALEOSTRIP,'tectonic',[]);
            setappdata(app.PALEOSTRIP,'SL_init',[]);
            setappdata(app.PALEOSTRIP,'EET_init',[]);
             
            cfilenames={};
            for ii=1:length(dfiles)
                cfilenames{ii}=dfiles(ii).name;
            end
            cfilenames=sort(cfilenames);


            %-----------------------------%
            % GET GRID DIMENSION OPTION
            %-----------------------------%
            if (app.Button_1D.Value)
                grid_opt = 1;
            elseif (app.Button_2D.Value)
                grid_opt = 2;
            elseif (app.Button_3D.Value)
                grid_opt = 3;
            end            
            
            h=msgbox('Importing data. Please wait.');
            delete(findobj(get(h,'children'),'String','OK'));
            drawnow;
            
            %----------- READ INPUT DATA
            [M, outputstring]=read_data(cfilenames,foldername,grid_opt);
            
            delete(h)
            
            if isempty(M)
                errordlg(outputstring, 'Error', 'modal');
                return
            end
            
            msgbox('Data imported.','', 'modal')
            
            set(app.TpushbuttonProcess,'enable','on')
            set(app.TpushbuttonSaveResults,'enable','off')
            set(app.TpushbuttonPlotResults,'enable','off')
            set(app.TpushbuttonContour,'enable','off');
            set(app.TpushbuttonSaveParameters,'enable','on')
            set(app.Tpushbutton_Extract,'enable','off')
            set(app.Tpushbutton_Plot_extracted,'enable','off')
            set(app.Tpushbutton_save_extracted,'enable','off')
            
            %populate table
            Data=cell(length(dfiles),6); %empty cell array
            for ii=1:length(dfiles) % column 5 is a string
                Data{ii,5}='';
            end
            
            for ii=1:length(params)
                Data{ii,1}=params(ii).Surpor;
                Data{ii,2}=params(ii).C;
                Data{ii,3}=params(ii).Rhos;
                Data{ii,4}=params(ii).AgeBase;
                Data{ii,5}=params(ii).Name;
            end
            
            for ii=1:length(dfiles)
                Data{ii,6}=cfilenames{ii};
            end
            Data{length(dfiles),5}='BATHYMETRY';
            
            set(app.TuitableParams,'Data',Data);
            
            setappdata(app.PALEOSTRIP,'directoryname',foldername);
            setappdata(app.PALEOSTRIP,'paramfile',paramfile);
            setappdata(app.PALEOSTRIP,'params',params);
            setappdata(app.PALEOSTRIP,'cfilenames',cfilenames);
            
            setappdata(app.PALEOSTRIP,'M',M);
            
            DataPoints={};
            DataPoints{1,1}=min(M(:,1));DataPoints{2,1}=max(M(:,1));
            DataPoints{1,2}=min(M(:,2));DataPoints{2,2}=max(M(:,2));
            
            set(app.TuitablePoints,'Data',DataPoints);
        end

        % Button pushed function: TpushbuttonProcess
        function TpushbuttonProcess_Callback(app, event)
            % hObject    handle to TpushbuttonProcess (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                addpath(genpath('./auxiliary'));
                addpath(genpath('./auxiliary/data'));
                addpath(genpath('./auxiliary/read_data'));
                addpath(genpath('./auxiliary/plot_save'));
                addpath(genpath('./auxiliary/plot_save/ScientificColourMaps7'));
                addpath(genpath('./auxiliary/mesh_grids'));
                addpath(genpath('./auxiliary/isostasy'));
                addpath(genpath('./auxiliary/isostasy/TAFI_v1.0'))
                addpath(genpath('./auxiliary/isostasy/flex3dv'));
            
            M=getappdata(app.PALEOSTRIP,'M');
            filecustom=getappdata(app.PALEOSTRIP,'filecustom');
            dircustom=getappdata(app.PALEOSTRIP,'dircustom');
            
            [params,outputstring]=getParamsFromTable(app);
            if isempty(params)
                errordlg(outputstring, 'Error', 'modal');
                return
            end
            
            
            compensatedM=M(:,1:end);
            
            
            h=msgbox('Processing data. Please wait.');
            delete(findobj(get(h,'children'),'String','OK'));
            drawnow;
            

%---------------------------------------------------------------------------
% GET OPTIONS FROM THE GUI
%---------------------------------------------------------------------------           
 
             %---------------------------------------------------------------------------
            % OPEN LOG FILE
            %---------------------------------------------------------------------------
            runid=app.edit_runid.Value;
            file_param=['backstripping_log_params_' runid '.txt'];
            
            if exist(file_param, 'file')==2
                delete(file_param);
            end
            
            logfile(app,'------------------','------------------',file_param)            
            logfile(app,'=PALEOSTRIP PARAMETERS LOG',' ',file_param)
            
            %-----------------------------%
            % GET GRID DIMENSION OPTION
            %-----------------------------%
            if (app.Button_1D.Value)
                grid_opt = 1;
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,"Grid dimension",'1D',file_param);
            elseif (app.Button_2D.Value)
                grid_opt = 2;
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,"Grid dimension",'2D',file_param); 
            elseif (app.Button_3D.Value)
                grid_opt = 3;
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,"Grid dimension",'3D',file_param);
            end             
            
            %-----------------------------%
            % GET DENSITY CONSTANTS
            %-----------------------------%
            Rhom = str2double(app.Rhom_val.Value);
            logfile(app,'------------------','------------------',file_param);            
            logfile(app,"Astenosphere_density",app.Rhom_val.Value,file_param);
            
            Rhoc = str2double(app.Rhoc_val.Value);
            logfile(app,'Crustal density' ,app.Rhoc_val.Value,file_param);
            
            Rhow = str2double(app.Rhow_val.Value);
            logfile(app,'Sea water density' ,app.Rhow_val.Value,file_param);


            %-----------------------------%
            %ISOSTASY
            %-----------------------------%
             
             EET_opt=0;
             YM = str2double(app.young_modulus_val.Value);
             v  = str2double(app.poisson_ratio_val.Value);
             EET= str2double(app.EET_val.Value) ;
             EETdir= ' ';
             EETfile=' ';   
                 
            if (app.IsostasyButton.Value)
                iso_menu = str2num(get(app.isomenu,'Value'));
                
                if (iso_menu == 1)
                    errordlg('Select an option to compute Isostasy', 'Error', 'modal');
                    return
                end

                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Isostasy','Yes',file_param)
                    
                % AIRY ISOSTASY
                if (iso_menu == 2)
                    iso_opt = 1 ;
                    logfile(app,'Isostasy','Airy',file_param)
                    
                    % FLEXURE
                elseif (iso_menu == 3)
                    iso_opt = 2 ;
                    
                    % Constant Effective Elastic Thickness
                    if(get(app.EET_ConstantButton,'Value')==1)
                       EET_opt=2;
                       logfile(app,'Isostasy','Flexure (Green function)',file_param)
                       logfile(app,'Eff. Elastic Thick.','Uniform',file_param)
                       logfile(app,'Eff. Elastic Thick.',num2str(EET),file_param)

                    % Spatially variable Effective Elastic Thickness   
                    elseif(get(app.EET_CustomButton,'Value')==1)
                       EET_opt=1;
                       logfile(app,'Isostasy','Flexure (Finite Difference)',file_param)
                       logfile(app,'Eff. Elastic Thick.','Variable',file_param)
                       logfile(app,'Eff. Elastic Thick.','User provided file',file_param)

                        if (grid_opt == 2)
                        EETfile=getappdata(app.PALEOSTRIP,'EETfile');
                        EETdir=getappdata(app.PALEOSTRIP,'EETdir');
                        logfile(app,'Eff. Elastic Thick.',fullfile(EETdir,EETfile),file_param)

                      elseif (grid_opt == 3)
                        EETfile=getappdata(app.PALEOSTRIP,'EETfile');
                        EETdir=getappdata(app.PALEOSTRIP,'EETdir');
                        logfile(app,'Eff. Elastic Thick.',fullfile(EETdir,EETfile),file_param)
                       end
                    end
                    
                    logfile(app,'Young modulus',num2str(YM),file_param)
                    logfile(app,'Poisson Ratio',num2str(v),file_param)

                end
                
                % NO ISOSTASY
            elseif (app.NoisostasyButton.Value) %(get(app.NoisostasyCheckBox,'Value')==1)
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Isostasy','No',file_param)
                iso_opt = 3 ;
            end


            %-----------------------------------%
            % VALUE OF EUSTATIC SEA LEVEL CHANGE
            %-----------------------------------%
            ds = 0;
            SLdir=' ';
            SLfile=' ';
            if (app.SealevelchangesButton.Value)
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Sea Level','Yes',file_param)
                
                if (get(app.radiobutton_ds,'Value') == 1)
                    opt_sl = 1;
                    ds = str2double(app.ds_val.Value) ;
                    logfile(app,'Sea Level',num2str(ds),file_param)
                    
                elseif (get(app.radiobutton_haq,'Value') == 1 )
                    opt_sl = 2;
                    logfile(app,'Sea Level','Haq',file_param)
                    
                elseif (get(app.radiobutton_miller,'Value') == 1)
                    opt_sl = 3;
                    logfile(app,'Sea Level','Miller',file_param)
                    
                elseif (get(app.UserTimeseriesButton,'Value') == 1) %% TO DO in 2D%%%%%%%%
                    opt_sl = 4;
                    SLfile=getappdata(app.PALEOSTRIP,'SLfile');
                    SLdir=getappdata(app.PALEOSTRIP,'SLdir');

                    logfile(app,'Sea Level','User-based',file_param)
                    logfile(app,'Sea Level','1D timeseries',file_param)
                    logfile(app,'Sea Level',fullfile(SLdir,SLfile),file_param)
                    
                elseif (get(app.UserSectionMapButton,'Value') == 1) %% TO DO in 2D%%%%%%%%
                    opt_sl = 5;
                    SLfile=getappdata(app.PALEOSTRIP,'SLfile');
                    SLdir=getappdata(app.PALEOSTRIP,'SLdir');                    
                    logfile(app,'Sea Level','User-based',file_param)
                    logfile(app,'Sea Level','2D section/map',file_param)
                    logfile(app,'Eff. Elastic Thick.',fullfile(SLdir,SLfile),file_param)
                    
                end
            elseif (app.NosealevelchangesButton.Value)
                opt_sl=6 ;
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Sea Level','No',file_param)
            end


            %-----------------------------%
            %THERMAL SUBSIDENCE
            %-----------------------------%
             arift = app.EndofriftingMaEditField.Value ;
             litho = app.InitiallithothicknesskmEditField.Value ;
             moho = app.InitialcrustalthicknesskmEditField.Value ;
             Aval = app.Thermalexpansioncoeff1CEditField.Value ;
             Kval = app.Thermaldiffusionoflithom2sEditField.Value ;
             Bval1 = app.Beta1EditField.Value ;
             Bval2 = app.Beta2EditField.Value ;
             CoeffB = app.CoeffEditField.Value ;
             opt_B = 0;
             beta_user = 0;
             betafile = ' ';
             betafolder = ' ';
            
             % THERMAL SUBSIDENCE
            if (app.ThermalsubsidenceButton.Value)
                thermal_subs = 1;
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Thermal Subsidence','Yes',file_param)
                logfile(app,'Age of rift (Ma)',num2str(arift),file_param)
                logfile(app,'Initial litho. thickness (km)',num2str(litho),file_param)
                logfile(app,'Initial crustal thickness (km)',num2str(moho),file_param)
                logfile(app,'Thermal diffusion coefficient (m2/s)',num2str(Aval),file_param)
                logfile(app,'Thermal expansion coeff. (1/C°)',num2str(Kval),file_param)
                
                
                % USER PROVIDED BETA PROFILE OR MAP
                if (app.CustomBetasectionormapButton.Value)
                    beta_user = 1;
                    betafile = getappdata(app.PALEOSTRIP,'BetaFile');
                    betafolder = getappdata(app.PALEOSTRIP,'BetaDir');
                    logfile(app,'Beta factor' ,'User provided file',file_param)
                    logfile(app,'Beta file',fullfile(betafolder,betafile),file_param)

                % Spatially uniform Beta (B1)                    
                elseif (app.UniformBetaB1Button.Value)
                    opt_B = 1;
                    logfile(app,'Beta factor' ,'Uniform',file_param)
                    logfile(app,'Beta factor 1',num2str(Bval1),file_param)
                    
                % SIMPLE BETA PARAMETRISATION FROM GUI    
                elseif(app.InterpolatedBetaB1B2Button.Value)

                    opt_B = str2num(app.BetaInterpolation.Value); % interpolation method
                    
                    if (iso_menu == 1)
                      errordlg('Select an option to compute Isostasy', 'Error', 'modal');
                      return
                    end
                    
                    if (opt_B == 2)
                        errordlg('Select an option to compute Beta factor', 'Error', 'modal');
                        return  
                    elseif (opt_B == 3)
                        logfile(app,'Beta factor' ,'Step_wise interp',file_param)
                        logfile(app,'coeff',num2str(CoeffB),file_param)
                        logfile(app,'Beta factor 1',num2str(Bval1),file_param)
                        logfile(app,'Beta factor 2',num2str(Bval2),file_param)
                    elseif (opt_B == 4)
                        logfile(app,'Beta factor' ,'Linear interp.',file_param)
                        logfile(app,'Beta factor 1',num2str(Bval1),file_param)
                        logfile(app,'Beta factor 2',num2str(Bval2),file_param)
                    end
                    
                end


            % NO THERMAL SUBSIDENCE
            elseif (app.NothermalsubsButton.Value)%(get(app.NothermalsubsidenceCheckBox,'Value')==1)
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Thermal subsidence' ,'No',file_param)
                thermal_subs = 0;
                opt_B = 0;
            end


            %-----------------------------------%
            % DYNAMIC TOPOGRAPHY CORRECTION
            %-----------------------------------%            
            dtopo_file= '';
            dtopo_dir='';
            dtopo_cte = 0.;
            if (app.DynamictopoButton.Value)
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Dynamic Topography' ,'Yes',file_param)               
                
                
                if (app.radiobutton_dtopo.Value)
                    opt_dyn = 1;
                    dtopo_cte = app.dtopo_val.Value
                    logfile(app,'Uniform Dynamic Topography relative to present',num2str(dtopo_cte),file_param)

                elseif (app.radiobutton_1D.Value) %% TO DO %%%%%%%%
                    opt_dyn = 2;

                    dtopo_file=getappdata(app.PALEOSTRIP,'dtfile1d');                                 
                    dtopo_dir=getappdata(app.PALEOSTRIP,'dtdir1d');

                    logfile(app,'Dynamic Topography','model',file_param)
                    logfile(app,'Dynamic Topography','1D',file_param)
                    logfile(app,'Dtopo files',fullfile(dtopo_dir,dtopo_file),file_param)

                elseif (app.radiobutton_2D3D.Value) %% TO DO %%%%%%%%
                    opt_dyn = 3;

                    dtopo_file=getappdata(app.PALEOSTRIP,'dtfile');                               
                    dtopo_dir=getappdata(app.PALEOSTRIP,'dtdir');

                    logfile(app,'Dynamic Topography','model',file_param)
                    logfile(app,'Dynamic Topography','2D/3D',file_param)
                    logfile(app,'Dynamic Topography',fullfile(dtopo_dir,dtopo_file),file_param)
                    %logfile('Sea Level',filecustom,handles,file_param)
                end
            elseif (app.NodynamictopoButton.Value)
                opt_dyn=4 ;
                
                
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'Dynamic Topography' ,'No',file_param) 
            end
                logfile(app,'------------------','------------------',file_param)            
                logfile(app,'=END OF LOG',' ',file_param)
                logfile(app,'------------------','------------------',file_param)            


%--------------------------------------------------------------------------%
% COMPUTE BACKTRACKING
%--------------------------------------------------------------------------%
            addpath(genpath('./auxiliary'));
            [out,outputstring]=backtracking(compensatedM,params,grid_opt,iso_opt,EET_opt,EETfile,EETdir,EET,YM,v,opt_sl,ds,...
                SLfile,SLdir,thermal_subs,arift,Aval,beta_user,betafile,betafolder,opt_B,...
                Bval1,Bval2,CoeffB,Kval,litho,moho,opt_dyn,dtopo_cte,dtopo_file,dtopo_dir,Rhom,Rhoc,Rhow);
           
            delete(h); drawnow;
            
            
%--------------------------------------------------------------------------%
% GET OUTPUTS
%--------------------------------------------------------------------------%
            if isempty(out)
                errordlg(outputstring, 'Error', 'modal');
                return
            end
            

            if any(isnan(out.Nzb(:)))
                out.Nzb(isnan(out.Nzb(:)))=0;
                warndlg('Decompaction completed. NaN values (Not a Number) obtained, possibly due to wrong parameters.','', 'modal');
            else
                msgbox('Decompaction completed.','', 'modal')
            end


            %GET OUTPUT VARIALBES READY FOR PLOTTING AND SAVING
            
                        
            depth= compensatedM(:,3:end);
            profiles(1).interface=-depth;
            profiles(1).description='profiles at time t0';
            profiles(1).layername='Present_day';
            profiles(1).age=0;
            
            profiles_max=max(out.Nzb,[],'all');
            profiles_min=min(-depth,[],'all');
            N=size(out.Nzb,2);
            for ii=2:N+1
                for jj = size(out.Nzb(:,N-ii+2,:),3):-1:1
                    if(nnz(out.Nzb(:,ii-1,jj))==0) % remove all previous horizons removed by backstripping and set to 0.
                        ' ';
                    else
                        profiles(ii).interface(:,jj)=out.Nzb(:,ii-1,jj);
                    end
                end
                
                if ii==N+1 % Today no layer removed
                    profiles(ii).description=['profiles at time -' params(N-ii+2).AgeBase];
                    profiles(ii).layername='Basement';
                    profiles(ii).age=params(N-ii+2).AgeBase;
                    
                else
                    
                    profiles(ii).description=['profiles at time -' params(N-ii+2).AgeBase];
                    profiles(ii).layername=params(N-ii+2).Name;
                    profiles(ii).age=params(N-ii+2).AgeBase;
                    
                end
                
            end

            
            %Get Layers thickness (decompacted isopach maps)            
            isopachs_max=max(out.Dt,[],'all');
            isopachs_min=min(out.Dt,[],'all');
            N=size(out.Dt,2);
            for ii=1:N%2:N+1
                
                for jj = size(out.Dt(:,N-ii+1,:),3):-1:1
                    if(nnz(out.Dt(:,ii,jj))==0) % remove all previous horizons removed by backstripping and set to 0.
                        ' ';
                    else
                        isopachs(ii).interface(:,jj)=out.Dt(:,ii,jj);
                    end
                end
                
                isopachs(ii).description=['Layers thickness at time -' params(N-ii+1).AgeBase];
                isopachs(ii).layername=params(N-ii+1).Name;
                isopachs(ii).age=params(N-ii+1).AgeBase;
                
            end
            
            
            %Get Layers density (Rhoa_units);
            
            density_max=max(out.Rhoa_units,[],'all');
            density_min=min(out.Rhoa_units,[],'all');
            N=size(out.Rhoa_units,2);
            for ii=1:N%2:N+1
                for jj = size(out.Rhoa_units(:,N-ii+1,:),3):-1:1
                    if(nnz(out.Rhoa_units(:,ii,jj))==0) % remove all previous horizons removed by backstripping and set to 0.
                        ' ';
                    else
                        density(ii).interface(:,jj)=out.Rhoa_units(:,ii,jj);
                    end
                end
                
                density(ii).description=['Layers density at time -' params(N-ii+1).AgeBase];
                density(ii).layername=params(N-ii+1).Name;
                density(ii).age=params(N-ii+1).AgeBase;
                
            end
            
            %Get Layers Porosity (Por);
            porosity_max=max(out.Por,[],'all');
            porosity_min=min(out.Por,[],'all');
            N=size(out.Por,2);
            for ii=1:N %2:N+1
                for jj = size(out.Por(:,N-ii+1,:),3):-1:1
                    if(nnz(out.Por(:,ii,jj))==0) % remove all previous horizons removed by backstripping and set to 0.
                        ' ';
                    else
                        porosity(ii).interface(:,jj)=out.Por(:,ii,jj);
                    end
                end
                
                porosity(ii).description=['Layers porosity at time -' params(N-ii+1).AgeBase];
                porosity(ii).layername=params(N-ii+1).Name;
                porosity(ii).age=params(N-ii+1).AgeBase;
                
            end
            
            %Get Isostasy (Por);
            isostasy_max=max(out.Iso_units,[],'all');
            isostasy_min=min(out.Iso_units,[],'all');
            N=size(out.Iso_units,2);
            for ii=1:N %2:N+1
                for jj = size(out.Iso_units(:,N-ii+1,:),3):-1:1
                    if(nnz(out.Iso_units(:,ii,jj))==0) % remove all previous horizons removed by backstripping and set to 0.
                        ' ';
                    else
                        isostasy(ii).interface(:,jj)=out.Iso_units(:,ii,jj);
                    end
                end

                isostasy(ii).description=['profiles at time -' params(N-ii+1).AgeBase];
                isostasy(ii).layername=params(N-ii+1).Name;
                isostasy(ii).age=params(N-ii+1).AgeBase;    
            end
            
            %Get Layers Thermal Subsidence (tsubs);
            tsubs_max=max(out.tsubs_corr,[],'all');
            tsubs_min=min(out.tsubs_corr,[],'all');
            N=size(out.tsubs_corr,2);
            Np=size(out.tsubs_corr,1);
            tsubs_corr=zeros(Np,N-1);
            for ii=1:N-1
                tsubs_corr(:,ii)=out.tsubs_corr(:,ii);
            end 
            
            %Get beta factor
            betaF_max=max(out.beta_factor,[],'all');
            betaF_min=min(out.beta_factor,[],'all');
            betaF=out.beta_factor;
            
            %Get Tectonic Subsidence;
            tectonic_max=max(out.Tect,[],'all');
            tectonic_min=min(out.Tect,[],'all');
            N=size(out.Tect,2);
            Np=size(out.Tect,1);
            for ii=2:N+1
                tectonic(:,ii)=out.Tect(:,ii-1); 
            end    
                tectonic(:,1) = -depth(:,1);% Present-day
                
            % Initial interpolated elastic thicknesses
            EET_init = out.EET;
            
            % Initial interpolated elastic thicknesses
            SL_init = out.SL;
            
            %Initial interpolated dynamic topography;
            N=size(out.dyntopo,2);
            Np=size(out.dyntopo,1);
            DynTopo_init=zeros(Np,N-1);
            for ii=1:N
                DynTopo_init(:,ii)=out.dyntopo(:,ii);
            end 
            
            setappdata(app.PALEOSTRIP,'profiles',profiles);
            setappdata(app.PALEOSTRIP,'density',density);
            setappdata(app.PALEOSTRIP,'porosity',porosity);
            setappdata(app.PALEOSTRIP,'isopachs',isopachs);
            setappdata(app.PALEOSTRIP,'tsubs_corr',tsubs_corr);
            setappdata(app.PALEOSTRIP,'isostasy',isostasy);
            setappdata(app.PALEOSTRIP,'betaF',betaF);
            setappdata(app.PALEOSTRIP,'tectonic',tectonic);
            % Initial conditions
            setappdata(app.PALEOSTRIP,'depth',depth);
            setappdata(app.PALEOSTRIP,'EET_init',EET_init);
            setappdata(app.PALEOSTRIP,'SL_init',SL_init);
            setappdata(app.PALEOSTRIP,'DynTopo_init',DynTopo_init);
             
            setappdata(app.PALEOSTRIP,'profiles_max',profiles_max);
            setappdata(app.PALEOSTRIP,'profiles_min',profiles_min);
            setappdata(app.PALEOSTRIP,'isopachs_max',isopachs_max);
            setappdata(app.PALEOSTRIP,'isopachs_min',isopachs_min);
            setappdata(app.PALEOSTRIP,'density_max',density_max);
            setappdata(app.PALEOSTRIP,'density_min',density_min);
            setappdata(app.PALEOSTRIP,'porosity_max',porosity_max);
            setappdata(app.PALEOSTRIP,'porosity_min',porosity_min);
            setappdata(app.PALEOSTRIP,'tsubs_max',tsubs_max);
            setappdata(app.PALEOSTRIP,'tsubs_min',tsubs_min);
            setappdata(app.PALEOSTRIP,'isostasy_max',isostasy_max);
            setappdata(app.PALEOSTRIP,'isostasy_min',isostasy_min);
            setappdata(app.PALEOSTRIP,'betaF_max',betaF_max);
            setappdata(app.PALEOSTRIP,'betaF_min',betaF_min);
            setappdata(app.PALEOSTRIP,'tectonic_max',tectonic_max);
            setappdata(app.PALEOSTRIP,'tectonic_min',tectonic_min);
            
            setappdata(app.PALEOSTRIP,'grid_opt',grid_opt);
            
%             set(app.Tpushbutton_SaveInit,'enable','on')
%             set(app.Tpushbutton_PlotInit,'enable','on')
            set(app.TpushbuttonPlotResults,'enable','on');
            set(app.TpushbuttonSaveResults,'enable','on');
            set(app.TpushbuttonContour,'enable','on');
            set(app.Tpushbutton_Extract,'enable','on')
            set(app.Tpushbutton_Plot_extracted,'enable','off')
            set(app.Tpushbutton_save_extracted,'enable','off')
        end

        % Button pushed function: TpushbuttonSaveParameters
        function TpushbuttonSaveParameters_Callback(app, event)
            % hObject    handle to TpushbuttonSaveParameters (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Save parameters to text file.
            % Example of formatted text:
            % [empty line]
            % NUMBER OF LAYERS =           8
            % [empty line]
            %   LAYER  POROSITY   DEC CON   MAT DEN  AGE BASE     NAME
            %                     (1/KM)   (KG / MC)   (MA)
            % [empty line]
            %      1    0.45       0.450     2680     0.600     layer1name
            %      2    0.45       0.450     2680     4.000     layer2name
            %      3    0.45       0.450     2680    10.000     layer3name
            %      4    0.45       0.450     2680    16.500     layer4name
            %      5    0.45       0.450     2680    19.000     layer5name
            %      6    0.45       0.450     2680    21.000     layer6name
            %      7    0.45       0.450     2680    26.000     layer7name
            %      8    0.45       0.270     2680    30.000     layer8name
            
            
            [params,outputstring]=getParamsFromTable(app);
            if isempty(params)
                errordlg(outputstring, 'Error', 'modal');
                return
            end
            [filename, pathname] = uiputfile('*.par', 'Select file') ;
            
            if isnumeric(filename)&&(filename==0)
                return
            end
            fid=fopen(fullfile(pathname,filename),'wt');
            fprintf(fid,'\n'); % empty line
            fprintf(fid,'NUMBER OF LAYERS =           %d\n',length(params)); % number of layers
            fprintf(fid,'\n'); % empty line
            fprintf(fid,'  LAYER  POROSITY   DEC CON   MAT DEN  AGE BASE     NAME\n'); % labels
            fprintf(fid,'                    (1/KM)   (KG / MC)   (MA)    \n'); % labels
            fprintf(fid,'\n'); % empty line
            for ii=1:length(params)
                fprintf(fid,'  %d    %4.4f    %4.4f    %4.2f    %3.2f    %s\n',ii,...
                    params(ii).Surpor,...
                    params(ii).C,...
                    params(ii).Rhos,...
                    params(ii).AgeBase,...
                    params(ii).Name);
                
            end
            fclose(fid);
        end

        % Button pushed function: pushbutton_load_profile
        function pushbutton_load_profile_Callback(app, event)

            [filecoord,coorddir] = uigetfile({'*.dat';'*.txt'},cd,'Select folder');
            
            setappdata(app.PALEOSTRIP,'filecoord',filecoord);
            setappdata(app.PALEOSTRIP,'coorddir',coorddir);
            set(app.Tpushbutton_Extract,'enable','on')
        end

        % Selection changed function: IsoButtonGroup
        function IsosatsySelection(app, event)
            %selectedButton = app.IsoButtonGroup.SelectedObject;
        end

        % Selection changed function: TSButtonGroup
        function ThermalSubsSelection(app, event)
            selectedButton = app.TSButtonGroup.SelectedObject;
        end

        % Selection changed function: SLButtonGroup
        function SLSelection(app, event)
            selectedButton = app.SLButtonGroup.SelectedObject;
        end

        % Selection changed function: DynTopoButtonGroup
        function DynTopoSelection(app, event)
            selectedButton = app.DynTopoButtonGroup.SelectedObject;
        end

        % Selection changed function: GridButtonGroup
        function GridSelection(app, event)
            selectedButton = app.GridButtonGroup.SelectedObject;
        end

        % Value changed function: plotresults
        function PlotResults_menu(app, event)
            %value = app.plotresults.Value;
        end

        % Button pushed function: TpushbuttonPlotResults
        function TpushbuttonPlotResults_Callback(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            isopachs=getappdata(app.PALEOSTRIP,'isopachs');
            density=getappdata(app.PALEOSTRIP,'density');
            porosity=getappdata(app.PALEOSTRIP,'porosity');
            tsubs_corr=getappdata(app.PALEOSTRIP,'tsubs_corr');
            isostasy=getappdata(app.PALEOSTRIP,'isostasy');
            tectonic=getappdata(app.PALEOSTRIP,'tectonic');
            
            profiles_max=getappdata(app.PALEOSTRIP,'profiles_max');
            profiles_min=getappdata(app.PALEOSTRIP,'profiles_min');
            isopachs_max=getappdata(app.PALEOSTRIP,'isopachs_max');
            isopachs_min=getappdata(app.PALEOSTRIP,'isopachs_min');
            density_max=getappdata(app.PALEOSTRIP,'density_max');
            density_min=getappdata(app.PALEOSTRIP,'density_min');
            porosity_max=getappdata(app.PALEOSTRIP,'porosity_max');
            porosity_min=getappdata(app.PALEOSTRIP,'porosity_min');
            tsubs_max=getappdata(app.PALEOSTRIP,'tsubs_max');
            tsubs_min=getappdata(app.PALEOSTRIP,'tsubs_min');
            isostasy_max=getappdata(app.PALEOSTRIP,'isostasy_max');
            isostasy_min=getappdata(app.PALEOSTRIP,'isostasy_min');
            tectonic_max=getappdata(app.PALEOSTRIP,'tectonic_max');
            tectonic_min=getappdata(app.PALEOSTRIP,'tectonic_min');
            
            % DEFINE GRID DIMENSION
            if (get(app.Button_1D,'Value')== 1)
              opt_grid = 1;
            elseif (get(app.Button_2D,'Value')== 1)
              opt_grid = 2;
            elseif (get(app.Button_3D,'Value')== 1)
              opt_grid = 3;
            end 
            
            %SELECT DATA TO BE PLOTTED
            plot_menu=str2num(get(app.plotresults,'Value'));
            
            % Get plot min and max if provided by user
            if (get(app.AutomaticlevelsButton,'Value')== 1)
                plot_levels=0;
                plot_min=0.;
                plot_max=0.;
            elseif (get(app.CustomlevelsButton,'Value')== 1)
                plot_levels=1;
                plot_min=app.MinEditField.Value;
                plot_max=app.MaxEditField.Value;
            end
            
            % SELECT THE TYPE OF PLOT (2D flat maps or real 3D) in case of
            % bacstripping of Maps (not working for profiles)
            if (get(app.Button_3Dmaps,'Value')== 1)
                maps_plot=1;
            elseif (get(app.Button_2Dmaps,'Value')== 1)
                maps_plot=0;
            end
            
            %SELECT COLORMAP
            palette=str2num(get(app.PaletteMenu2,'Value'));
            
            % Flip palette upside down
            if (get(app.FlipUD2_No,'Value')== 1)
                palette_FUD=0;
            elseif (get(app.FlipUD2_Yes,'Value')== 1)
                palette_FUD=1;
            end
            
            msg=msgbox('Plotting data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            
            % Call function in ./auxiliary/plot_save/
            Plot_Results(M,opt_grid,plot_menu,maps_plot,plot_min,plot_max,palette,palette_FUD,plot_levels,profiles,profiles_min,profiles_max,isopachs,isopachs_min,isopachs_max,...
                density,density_min,density_max,porosity,porosity_min,porosity_max,tsubs_corr,tsubs_min,...
                tsubs_max,isostasy,isostasy_min,isostasy_max,tectonic,tectonic_min,tectonic_max)
            
            delete(msg);drawnow;
        end

        % Selection changed function: maps2D3D_selection
        function Plot2D3DSelection(app, event)
            %selectedButton = app.maps2D3D_selection.SelectedObject;
        end

        % Value changed function: saveresults
        function SaveResults_menu(app, event)
            %value = app.saveresults.Value;
        end

        % Button pushed function: TpushbuttonSaveResults
        function TpushbuttonSaveResults_Callback(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            isopachs=getappdata(app.PALEOSTRIP,'isopachs');
            density=getappdata(app.PALEOSTRIP,'density');
            porosity=getappdata(app.PALEOSTRIP,'porosity');
            tsubs_corr=getappdata(app.PALEOSTRIP,'tsubs_corr');
            isostasy=getappdata(app.PALEOSTRIP,'isostasy');
            tectonic=getappdata(app.PALEOSTRIP,'tectonic');

            % DEFINE GRID DIMENSION
              if (get(app.Button_1D,'Value')== 1)
                  opt_grid = 1;
              elseif (get(app.Button_2D,'Value')== 1)
                  opt_grid = 2;
              elseif (get(app.Button_3D,'Value')== 1)
                  opt_grid = 3;
              end    
            
            % RUNID String
            runid=app.edit_runid.Value;

              
            %OUTPUT DIRECTORY  
            destinationfolder=uigetdir;
            if isnumeric(destinationfolder)
                return
            end
            
            %SELECT LENGTH DATA TO BE PLOTTED
            save_menu=str2num(get(app.saveresults,'Value'));
            
            msg=msgbox('Saving data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            
            % Call function in ./auxiliary/plot_save/
            Save_Results(M,save_menu,opt_grid,runid,destinationfolder,profiles,isopachs,density,porosity,tsubs_corr,isostasy,tectonic)
            
            delete(msg);drawnow;
            msgbox('Results successfully saved.','', 'modal')
        end

        % Button pushed function: TpushbuttonContour
        function TpushbuttonContour_Callback(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            isopachs=getappdata(app.PALEOSTRIP,'isopachs');
            density=getappdata(app.PALEOSTRIP,'density');
            porosity=getappdata(app.PALEOSTRIP,'porosity');
            tsubs_corr=getappdata(app.PALEOSTRIP,'tsubs_corr');
            isostasy=getappdata(app.PALEOSTRIP,'isostasy');
            tectonic=getappdata(app.PALEOSTRIP,'tectonic');
            
            profiles_max=getappdata(app.PALEOSTRIP,'profiles_max');
            profiles_min=getappdata(app.PALEOSTRIP,'profiles_min');
            isopachs_max=getappdata(app.PALEOSTRIP,'isopachs_max');
            isopachs_min=getappdata(app.PALEOSTRIP,'isopachs_min');
            density_max=getappdata(app.PALEOSTRIP,'density_max');
            density_min=getappdata(app.PALEOSTRIP,'density_min');
            porosity_max=getappdata(app.PALEOSTRIP,'porosity_max');
            porosity_min=getappdata(app.PALEOSTRIP,'porosity_min');
            tsubs_max=getappdata(app.PALEOSTRIP,'tsubs_max');
            tsubs_min=getappdata(app.PALEOSTRIP,'tsubs_min');
            isostasy_max=getappdata(app.PALEOSTRIP,'isostasy_max');
            isostasy_min=getappdata(app.PALEOSTRIP,'isostasy_min');
            tectonic_max=getappdata(app.PALEOSTRIP,'tectonic_max');
            tectonic_min=getappdata(app.PALEOSTRIP,'tectonic_min');
            
            
            %SELECT LENGTH DATA TO BE PLOTTED
            plot_menu=str2num(get(app.plotresults,'Value'));
            
            % Get plot min and max if provided by user
            if (get(app.AutomaticlevelsButton,'Value')== 1)
                plot_levels=0;
                plot_min=0.;
                plot_max=0.;
            elseif (get(app.CustomlevelsButton,'Value')== 1)
                plot_levels=1;
                plot_min=app.MinEditField.Value;
                plot_max=app.MaxEditField.Value;
            end
            
            % SELECT THE TYPE OF PLOT (2D flat maps or real 3D) in case of
            % bacstripping of Maps (not working for profiles)
            if (get(app.Button_3Dmaps,'Value')== 1)
                maps_plot=1;
            elseif (get(app.Button_2Dmaps,'Value')== 1)
                maps_plot=0;
            end
            
            % DEFINE GRID DIMENSION
             if (get(app.Button_1D,'Value')== 1)
                  opt_grid = 1;
             elseif (get(app.Button_2D,'Value')== 1)
                  opt_grid = 2;
             elseif (get(app.Button_3D,'Value')== 1)
                  opt_grid = 3;
             end 
            
            
            msg=msgbox('Plotting data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
          
            % Call function in ./auxiliary/plot_save/
            Plot_contours(M,plot_menu,maps_plot,plot_min,plot_max,plot_levels,profiles,profiles_min,profiles_max,isopachs,isopachs_min,isopachs_max,...
                density,density_min,density_max,porosity,porosity_min,porosity_max,tsubs_corr,tsubs_min,...
                tsubs_max,isostasy,isostasy_min,isostasy_max,tectonic,tectonic_min,tectonic_max)
            
            delete(msg);drawnow;
        end

        % Value changed function: isomenu
        function Isostasy_menu(app, event)
            %value = app.isomenu.Value;
        end

        % Button pushed function: pushbutton_file_EET
        function Custom_EET_File_Button(app, event)
            [EETfile,EETdir] = uigetfile({'*.dat';'*.txt'},cd,'Select folder');
            setappdata(app.PALEOSTRIP,'EETfile',EETfile);
            setappdata(app.PALEOSTRIP,'EETdir',EETdir);
        end

        % Value changed function: BetaInterpolation
        function Beta_menu(app, event)
            %value = app.BetaInterpolation.Value;
        end

        % Callback function
        function BetaSelection(app, event)
            %selectedButton = app.StrechingFactorButtonGroup.SelectedObject;
        end

        % Button pushed function: pushbutton_custom_Beta
        function Custom_Beta_File_Button(app, event)
            [BetaFile,BetaDir] = uigetfile({'*.dat';'*.txt'},cd,'Select folder');
            setappdata(app.PALEOSTRIP,'betaFile',BetaFile);
            setappdata(app.PALEOSTRIP,'betaDir',BetaDir);  
        end

        % Value changed function: ExtractVar
        function Extract_menu(app, event)
           % value = app.ExtractVar.Value;
        end

        % Button pushed function: Tpushbutton_Extract
        function Tpushbutton_ExtractButton(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            isopachs=getappdata(app.PALEOSTRIP,'isopachs');
            density=getappdata(app.PALEOSTRIP,'density');
            porosity=getappdata(app.PALEOSTRIP,'porosity');
            tsubs_corr=getappdata(app.PALEOSTRIP,'tsubs_corr');
            isostasy=getappdata(app.PALEOSTRIP,'isostasy');
            betaF=getappdata(app.PALEOSTRIP,'betaF');
            tectonic=getappdata(app.PALEOSTRIP,'tectonic');          
            
            % VARIABLE TO BE EXTRACTED & PLOTTED
            plot_extracted=str2num(app.ExtractVar.Value);
            
             if (plot_extracted == 1)
                errordlg('Select a variable first', 'Error', 'modal');
                return 
             end
            
            h=msgbox('Extracting data. Please wait.');
            delete(findobj(get(h,'children'),'String','OK'));
            drawnow;

            
            if (get(app.radiobutton_coord_table,'Value')==1)
                opt_extract=1 ;
                DataPoints=get(app.TuitablePoints,'Data');
              
            elseif(get(app.radiobutton_coord_file,'Value')==1)
              opt_extract=2 ;
              filecoord=getappdata(app.PALEOSTRIP,'filecoord');
              coorddir=getappdata(app.PALEOSTRIP,'coorddir');
              [DataPoints, outputstring]=read_imported_coord(filecoord,coorddir);
            end
                      
             X=M(:,1);
             Y=M(:,2);

             [extract_prof,Xpts,Ypts]=Extract_Var(opt_extract,plot_extracted,DataPoints,X,Y,profiles,...
                 isopachs,density,porosity,tsubs_corr,isostasy,betaF,tectonic);
            
            delete(h);drawnow;
            
            msgbox('Profile extracted successfully!','', 'modal')
            
            setappdata(app.PALEOSTRIP,'extract_prof',extract_prof);
            setappdata(app.PALEOSTRIP,'Xpts',Xpts);
            setappdata(app.PALEOSTRIP,'Ypts',Ypts);
            set(app.Tpushbutton_Plot_extracted,'enable','on')
            set(app.Tpushbutton_save_extracted,'enable','on')
 
        end

        % Button pushed function: Tpushbutton_Plot_extracted
        function Tpushbutton_Plot_Extracted(app, event)
            M=getappdata(app.PALEOSTRIP,'M');  % To get names and Ages           
            profiles=getappdata(app.PALEOSTRIP,'profiles');  % To get names and Ages           
            extract_prof=getappdata(app.PALEOSTRIP,'extract_prof');
            Xpts=getappdata(app.PALEOSTRIP,'Xpts');
            
            msg=msgbox('Plotting data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            
            %GET EXTRACTED VARIABLES AND PLOT OPTIONS
            plot_extracted=str2num(app.ExtractVar.Value);

             if (plot_extracted == 1)
                    errordlg('Select a variable first', 'Error', 'modal');
                    return 
             end
            
            Plot_Extracted(M,plot_extracted,extract_prof,Xpts,profiles);
            
            delete(msg);drawnow;
            msgbox('Plotting successfull!',' ', 'modal')

        end

        % Button pushed function: Tpushbutton_save_extracted
        function Tpushbutton_Save_Extracted(app, event)
            extract_prof=getappdata(app.PALEOSTRIP,'extract_prof');
            Xpts=getappdata(app.PALEOSTRIP,'Xpts');
            Ypts=getappdata(app.PALEOSTRIP,'Ypts');
            profiles=getappdata(app.PALEOSTRIP,'profiles');  % To get names and Ages           

            
            % RUNID String
            extractid=app.edit_profile_name.Value;           
              
            %OUTPUT DIRECTORY  
            destinationfolder=uigetdir;
            if isnumeric(destinationfolder)
                return
            end
            
                
            % VARIABLE TO BE SAVED
            plot_extracted=str2num(app.ExtractVar.Value);
                
            if (plot_extracted == 1)
                    errordlg('Select a variable first', 'Error', 'modal');
                    return 
            end
            
            msg=msgbox('Saving results. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            

            Save_extracted(plot_extracted,destinationfolder,extractid,extract_prof,Xpts,Ypts,profiles)
                 
                     
            delete(msg);drawnow;
            msgbox('Results successfully saved.','', 'modal')

        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: pushbutton_customsl
        function pushbutton_customslButtonPushed(app, event)
            [SLfile,SLdir] = uigetfile({'*.dat';'*.txt'},cd,'Select folder');
            setappdata(app.PALEOSTRIP,'SLfile',SLfile);
            setappdata(app.PALEOSTRIP,'SLdir',SLdir); 
        end

        % Button pushed function: PlotButton
        function Tpushbuttom_PlotInit(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            depth=getappdata(app.PALEOSTRIP,'depth');
            EET_init=getappdata(app.PALEOSTRIP,'EET_init');
            SL_init=getappdata(app.PALEOSTRIP,'SL_init');
            DynTopo_init=getappdata(app.PALEOSTRIP,'DynTopo_init');
            betaF=getappdata(app.PALEOSTRIP,'betaF');

             % DEFINE GRID DIMENSION
              if (get(app.Button_1D,'Value')== 1)
                  opt_grid = 1;
              elseif (get(app.Button_2D,'Value')== 1)
                  opt_grid = 2;
              elseif (get(app.Button_3D,'Value')== 1)
                  opt_grid = 3;
              end 
              
            %SELECT DATA TO BE PLOTTED
            plot_init=str2num(get(app.InitialData,'Value'));
            
            % Get plot min and max if provided by user
            if (get(app.AutomaticlevelsButton_2,'Value')== 1)
                plot_levels=0;
                plot_min=0.;
                plot_max=0.;
            elseif (get(app.CustomlevelsButton_2,'Value')== 1)
                plot_levels=1;
                plot_min=app.MinEditField_2.Value;
                plot_max=app.MaxEditField_2.Value;
            end
            
            % SELECT THE TYPE OF PLOT (2D flat maps or real 3D) in case of
            % bacstripping of Maps (not working for profiles)
            if (get(app.plainmaps2DButton,'Value')== 1)
                maps_plot=0;
            elseif (get(app.real3DmapsButton,'Value')== 1)
                maps_plot=1;
            end
            
            %SELECT COLORMAP
            palette=str2num(get(app.PaletteMenu,'Value'));
            
            % Flip palette upside down
            if (get(app.FlipUD_No,'Value')== 1)
                palette_FUD=0;
            elseif (get(app.FlipUD_Yes,'Value')== 1)
                palette_FUD=1;
            end
            
            msg=msgbox('Plotting data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            
            % Call function in ./auxiliary/plot_save/
            Plot_Init(M,opt_grid,plot_init,maps_plot,plot_min,plot_max,palette,palette_FUD,plot_levels,profiles,depth,EET_init,SL_init,DynTopo_init,betaF)
            
            delete(msg);drawnow; 
        end

        % Size changed function: SeaLevelTab
        function SeaLevelTabSizeChanged(app, event)
            %position = app.SeaLevelTab.Position;
            [SLfile,SLdir] = uigetfile({'*.dat';'*.txt'},cd,'Select folder');
            setappdata(app.PALEOSTRIP,'SLfile',SLfile);
            setappdata(app.PALEOSTRIP,'SLdir',SLdir);
        end

        % Button pushed function: SaveInitButton
        function Tpushbutton_SaveInit(app, event)
            M=getappdata(app.PALEOSTRIP,'M');
            profiles=getappdata(app.PALEOSTRIP,'profiles');
            depth=getappdata(app.PALEOSTRIP,'depth');
            EET_init=getappdata(app.PALEOSTRIP,'EET_init');
            SL_init=getappdata(app.PALEOSTRIP,'SL_init');
            betaF=getappdata(app.PALEOSTRIP,'betaF');
            DynTopo_init=getappdata(app.PALEOSTRIP,'DynTopo_init');


            % DEFINE GRID DIMENSION
              if (get(app.Button_1D,'Value')== 1)
                  opt_grid = 1;
              elseif (get(app.Button_2D,'Value')== 1)
                  opt_grid = 2;
              elseif (get(app.Button_3D,'Value')== 1)
                  opt_grid = 3;
              end    
            
            % RUNID String
            runid=app.edit_runid.Value;

              
            %OUTPUT DIRECTORY  
            destinationfolder=uigetdir;
            if isnumeric(destinationfolder)
                return
            end
            
            %SELECT DATA TO BE SAVED
            plot_init=str2num(get(app.InitialData,'Value'));
            
            msg=msgbox('Saving initial data. Please wait.');
            delete(findobj(get(msg,'children'),'String','OK'));
            drawnow;
            
            % Call function in ./auxiliary/plot_save/
            Save_Init(M,plot_init,opt_grid,runid,destinationfolder,profiles,depth,EET_init,SL_init,DynTopo_init,betaF)
            
            delete(msg);drawnow;
            msgbox('Results successfully saved.','', 'modal')
        end

        % Button pushed function: Openfile_2D3D
        function Tpushbutton_dtopo_file(app, event)
            [dtfile,dtdir] = uigetfile({'*'},cd,'Select folder');
            setappdata(app.PALEOSTRIP,'dtfile',dtfile);
            setappdata(app.PALEOSTRIP,'dtdir',dtdir);
        end

        % Button pushed function: Openfile_1D
        function Openfile_1DButtonPushed(app, event)
          [dtfile1d,dtdir1d] = uigetfile({'*'},cd,'Select folder');
           setappdata(app.PALEOSTRIP,'dtfile1d',dtfile1d);
           setappdata(app.PALEOSTRIP,'dtdir1d',dtdir1d);   
        end

        % Value changed function: PaletteMenu2
        function Palette_menu2(app, event)
            %value = app.PaletteMenu2.Value;  
        end

        % Selection changed function: FlipUD2_Button
        function FlipUD2_RadioButton(app, event)
            %selectedButton = app.FlipUD2_Button.SelectedObject;
        end

        % Value changed function: PaletteMenu
        function Palette_menu(app, event)
            %value = app.PaletteMenu.Value;
        end

        % Selection changed function: FlipUD_Button
        function FlipUD_RadioButton(app, event)
            %selectedButton = app.FlipUD_Button.SelectedObject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PALEOSTRIP = uifigure('Visible', 'off');
            app.PALEOSTRIP.Name = 'PALEOSTRIP';
            app.text1 = uilabel(app.PALEOSTRIP);
            app.text1.Text = 'PALEOSTRIP v1.0';
            app.TabGroup2 = uitabgroup(app.PALEOSTRIP);
            app.BACKTRACKINGTab = uitab(app.TabGroup2);
            app.BACKTRACKINGTab.Title = 'BACKTRACKING';
            app.TabGroup = uitabgroup(app.BACKTRACKINGTab);
            app.IsostasyTab = uitab(app.TabGroup);
            app.IsostasyTab.Title = 'Isostasy';
            app.IsoButtonGroup = uibuttongroup(app.IsostasyTab);
            app.IsoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IsosatsySelection, true);
            app.IsostasyButton = uiradiobutton(app.IsoButtonGroup);
            app.IsostasyButton.Text = 'Isostasy';
            app.NoisostasyButton = uiradiobutton(app.IsoButtonGroup);
            app.NoisostasyButton.Text = 'No isostasy';
            app.NoisostasyButton.Value = true;
            app.text_flexural_params = uilabel(app.IsostasyTab);
            app.text_flexural_params.Text = 'For flexural isostasy only:';
            app.text_EET_selection = uilabel(app.IsostasyTab);
            app.text_EET_selection.Text = 'Effective Elastic Thickness (km):';
            app.text_Young_modulus = uilabel(app.IsostasyTab);
            app.text_Young_modulus.Text = 'Young modulus (Pa):';
            app.text_Poisson = uilabel(app.IsostasyTab);
            app.text_Poisson.Text = 'Poisson Ratio:';
            app.poisson_ratio_val = uieditfield(app.IsostasyTab, 'text');
            app.poisson_ratio_val.Value = '0.25';
            app.young_modulus_val = uieditfield(app.IsostasyTab, 'text');
            app.young_modulus_val.Value = '7e10';
            app.EET_val = uieditfield(app.IsostasyTab, 'text');
            app.EET_val.Value = '30.';
            app.pushbutton_file_EET = uibutton(app.IsostasyTab, 'push');
            app.pushbutton_file_EET.ButtonPushedFcn = createCallbackFcn(app, @Custom_EET_File_Button, true);
            app.pushbutton_file_EET.Text = 'Open file';
            app.EETButton = uibuttongroup(app.IsostasyTab);
            app.EET_ConstantButton = uiradiobutton(app.EETButton);
            app.EET_ConstantButton.Text = 'Uniform';
            app.EET_ConstantButton.Value = true;
            app.EET_CustomButton = uiradiobutton(app.EETButton);
            app.EET_CustomButton.Text = 'Variable';
            app.isomenu = uidropdown(app.IsostasyTab);
            app.isomenu.Items = {'Choose Isostasy', 'Airy', 'Flexure'};
            app.isomenu.ItemsData = {'1', '2', '3'};
            app.isomenu.ValueChangedFcn = createCallbackFcn(app, @Isostasy_menu, true);
            app.isomenu.Value = '1';
            app.ThermalsubsidenceTab = uitab(app.TabGroup);
            app.ThermalsubsidenceTab.Title = 'Thermal subsidence';
            app.TSButtonGroup = uibuttongroup(app.ThermalsubsidenceTab);
            app.TSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThermalSubsSelection, true);
            app.ThermalsubsidenceButton = uiradiobutton(app.TSButtonGroup);
            app.ThermalsubsidenceButton.Text = 'Thermal subsidence';
            app.NothermalsubsButton = uiradiobutton(app.TSButtonGroup);
            app.NothermalsubsButton.Text = 'No thermal subs.';
            app.NothermalsubsButton.Value = true;
            app.StrechingFactorButtonGroup = uibuttongroup(app.ThermalsubsidenceTab);
            app.StrechingFactorButtonGroup.Title = 'Streching Factor';
            app.UniformBetaB1Button = uiradiobutton(app.StrechingFactorButtonGroup);
            app.UniformBetaB1Button.Text = 'Uniform Beta (B1)';
            app.UniformBetaB1Button.Value = true;
            app.CustomBetasectionormapButton = uiradiobutton(app.StrechingFactorButtonGroup);
            app.CustomBetasectionormapButton.Text = 'Custom Beta (section or map)';
            app.pushbutton_custom_Beta = uibutton(app.StrechingFactorButtonGroup, 'push');
            app.pushbutton_custom_Beta.ButtonPushedFcn = createCallbackFcn(app, @Custom_Beta_File_Button, true);
            app.pushbutton_custom_Beta.Text = 'Open file';
            app.BetaInterpolation = uidropdown(app.StrechingFactorButtonGroup);
            app.BetaInterpolation.Items = {'Beta factor interpolation', 'Step-wise B', 'Linear interp.'};
            app.BetaInterpolation.ItemsData = {'2', '3', '4'};
            app.BetaInterpolation.ValueChangedFcn = createCallbackFcn(app, @Beta_menu, true);
            app.BetaInterpolation.Value = '2';
            app.InterpolatedBetaB1B2Button = uiradiobutton(app.StrechingFactorButtonGroup);
            app.InterpolatedBetaB1B2Button.Text = 'Interpolated Beta (B1 & B2)';
            app.Beta1EditFieldLabel = uilabel(app.StrechingFactorButtonGroup);
            app.Beta1EditFieldLabel.Text = 'Beta 1';
            app.Beta1EditField = uieditfield(app.StrechingFactorButtonGroup, 'numeric');
            app.Beta1EditField.Value = 2.3;
            app.Beta2Label = uilabel(app.StrechingFactorButtonGroup);
            app.Beta2Label.Text = 'Beta 2 ';
            app.Beta2EditField = uieditfield(app.StrechingFactorButtonGroup, 'numeric');
            app.Beta2EditField.Value = 2.8;
            app.CoeffEditFieldLabel = uilabel(app.StrechingFactorButtonGroup);
            app.CoeffEditFieldLabel.Text = 'Coeff.';
            app.CoeffEditField = uieditfield(app.StrechingFactorButtonGroup, 'numeric');
            app.CoeffEditField.Value = 1;
            app.EndofriftingMaEditFieldLabel = uilabel(app.ThermalsubsidenceTab);
            app.EndofriftingMaEditFieldLabel.Text = 'End of rifting (Ma)';
            app.EndofriftingMaEditField = uieditfield(app.ThermalsubsidenceTab, 'numeric');
            app.EndofriftingMaEditField.Value = 76;
            app.InitiallithothicknesskmEditFieldLabel = uilabel(app.ThermalsubsidenceTab);
            app.InitiallithothicknesskmEditFieldLabel.Text = 'Initial litho. thickness (km)';
            app.InitiallithothicknesskmEditField = uieditfield(app.ThermalsubsidenceTab, 'numeric');
            app.InitiallithothicknesskmEditField.Value = 125;
            app.InitialcrustalthicknesskmEditFieldLabel = uilabel(app.ThermalsubsidenceTab);
            app.InitialcrustalthicknesskmEditFieldLabel.Text = 'Initial crustal thickness (km)';
            app.InitialcrustalthicknesskmEditField = uieditfield(app.ThermalsubsidenceTab, 'numeric');
            app.InitialcrustalthicknesskmEditField.Value = 35;
            app.Thermalexpansioncoeff1CEditFieldLabel = uilabel(app.ThermalsubsidenceTab);
            app.Thermalexpansioncoeff1CEditFieldLabel.Text = 'Thermal expansion coeff. (1/°C)';
            app.Thermalexpansioncoeff1CEditField = uieditfield(app.ThermalsubsidenceTab, 'numeric');
            app.Thermalexpansioncoeff1CEditField.Value = 3.45e-05;
            app.Thermaldiffusionoflithom2sEditFieldLabel = uilabel(app.ThermalsubsidenceTab);
            app.Thermaldiffusionoflithom2sEditFieldLabel.Text = 'Thermal diffusion of litho. (m2/s)';
            app.Thermaldiffusionoflithom2sEditField = uieditfield(app.ThermalsubsidenceTab, 'numeric');
            app.Thermaldiffusionoflithom2sEditField.Value = 7.8e-07;
            app.SeaLevelTab = uitab(app.TabGroup);
            app.SeaLevelTab.SizeChangedFcn = createCallbackFcn(app, @SeaLevelTabSizeChanged, true);
            app.SeaLevelTab.Title = 'Sea Level';
            app.SLButtonGroup = uibuttongroup(app.SeaLevelTab);
            app.SLButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLSelection, true);
            app.SealevelchangesButton = uiradiobutton(app.SLButtonGroup);
            app.SealevelchangesButton.Text = 'Sea level changes';
            app.NosealevelchangesButton = uiradiobutton(app.SLButtonGroup);
            app.NosealevelchangesButton.Text = 'No sea level changes';
            app.NosealevelchangesButton.Value = true;
            app.SL_method_buttongroup = uibuttongroup(app.SeaLevelTab);
            app.ds_val = uieditfield(app.SL_method_buttongroup, 'text');
            app.ds_val.Value = '0.';
            app.pushbutton_customsl = uibutton(app.SL_method_buttongroup, 'push');
            app.pushbutton_customsl.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_customslButtonPushed, true);
            app.pushbutton_customsl.Text = 'Open File';
            app.radiobutton_ds = uiradiobutton(app.SL_method_buttongroup);
            app.radiobutton_ds.Text = 'Constant and uniform SL (m)';
            app.radiobutton_ds.Value = true;
            app.radiobutton_haq = uiradiobutton(app.SL_method_buttongroup);
            app.radiobutton_haq.Text = 'Time evolving SL (Haq)';
            app.radiobutton_miller = uiradiobutton(app.SL_method_buttongroup);
            app.radiobutton_miller.Text = 'Time evolving SL (Miller et al., 2020)';
            app.UserTimeseriesButton = uiradiobutton(app.SL_method_buttongroup);
            app.UserTimeseriesButton.Text = '1D timeseries (time-dependent)';
            app.timeseriesLabel = uilabel(app.SL_method_buttongroup);
            app.timeseriesLabel.Text = '1D timeseries:';
            app.UserSectionMapButton = uiradiobutton(app.SL_method_buttongroup);
            app.UserSectionMapButton.Text = '2D section/map (constant)';
            app.timeseriesLabel_2 = uilabel(app.SL_method_buttongroup);
            app.timeseriesLabel_2.Text = 'User-based SL:';
            app.DynamictopographyTab = uitab(app.TabGroup);
            app.DynamictopographyTab.Title = 'Dynamic topography';
            app.DynTopoButtonGroup = uibuttongroup(app.DynamictopographyTab);
            app.DynTopoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DynTopoSelection, true);
            app.DynamictopoButton = uiradiobutton(app.DynTopoButtonGroup);
            app.DynamictopoButton.Text = 'Dynamic topo.';
            app.NodynamictopoButton = uiradiobutton(app.DynTopoButtonGroup);
            app.NodynamictopoButton.Text = 'No dynamic topo.';
            app.NodynamictopoButton.Value = true;
            app.uibuttongroup18_2 = uibuttongroup(app.DynamictopographyTab);
            app.radiobutton_dtopo = uiradiobutton(app.uibuttongroup18_2);
            app.radiobutton_dtopo.Text = 'Uniform correction  (m):';
            app.radiobutton_dtopo.Value = true;
            app.radiobutton_2D3D = uiradiobutton(app.uibuttongroup18_2);
            app.radiobutton_2D3D.Text = '2D profiles/3D maps';
            app.Openfile_2D3D = uibutton(app.uibuttongroup18_2, 'push');
            app.Openfile_2D3D.ButtonPushedFcn = createCallbackFcn(app, @Tpushbutton_dtopo_file, true);
            app.Openfile_2D3D.Text = 'Open File';
            app.dtopo_val = uieditfield(app.uibuttongroup18_2, 'numeric');
            app.radiobutton_1D = uiradiobutton(app.uibuttongroup18_2);
            app.radiobutton_1D.Text = '1D timeseries';
            app.Openfile_1D = uibutton(app.uibuttongroup18_2, 'push');
            app.Openfile_1D.ButtonPushedFcn = createCallbackFcn(app, @Openfile_1DButtonPushed, true);
            app.Openfile_1D.Text = 'Open file';
            app.CorrectionrelativetopresentLabel = uilabel(app.uibuttongroup18_2);
            app.CorrectionrelativetopresentLabel.Text = 'Correction relative to present:';
            app.TpushbuttonProcess = uibutton(app.BACKTRACKINGTab, 'push');
            app.TpushbuttonProcess.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonProcess_Callback, true);
            app.TpushbuttonProcess.Enable = 'off';
            app.TpushbuttonProcess.Text = 'RUN BACKTRACKING';
            app.ImportData_uipanel = uipanel(app.BACKTRACKINGTab);
            app.ImportData_uipanel.Title = '1 - Load Data & Parameters';
            app.TuitableParams = uitable(app.ImportData_uipanel);
            app.TuitableParams.ColumnName = {'POROSITY'; 'DEC CON'; 'MAT DEN'; 'AGE BASE'; 'NAME'; 'DATA FILE'};
            app.TuitableParams.ColumnEditable = [true true true true true false];
            app.TpushbuttonImportData = uibutton(app.ImportData_uipanel, 'push');
            app.TpushbuttonImportData.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonImportData_Callback, true);
            app.TpushbuttonImportData.Text = 'Import Data';
            app.TpushbuttonSaveParameters = uibutton(app.ImportData_uipanel, 'push');
            app.TpushbuttonSaveParameters.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonSaveParameters_Callback, true);
            app.TpushbuttonSaveParameters.Enable = 'off';
            app.TpushbuttonSaveParameters.Text = 'Save Parameters';
            app.Grid_Selection = uibuttongroup(app.ImportData_uipanel);
            app.Grid_Selection.Title = 'Grid dimension';
            app.GridButtonGroup = uibuttongroup(app.Grid_Selection);
            app.GridButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GridSelection, true);
            app.Button_1D = uiradiobutton(app.GridButtonGroup);
            app.Button_1D.Text = '1D (well)';
            app.Button_1D.Value = true;
            app.Button_2D = uiradiobutton(app.GridButtonGroup);
            app.Button_2D.Text = '2D (profile)';
            app.Button_3D = uiradiobutton(app.GridButtonGroup);
            app.Button_3D.Text = '3D (map)';
            app.text_runid = uilabel(app.ImportData_uipanel);
            app.text_runid.Text = 'Run ID:';
            app.edit_runid = uieditfield(app.ImportData_uipanel, 'text');
            app.edit_runid.Value = 'run_01';
            app.text24 = uilabel(app.ImportData_uipanel);
            app.text24.Text = 'Mantle avg. density (kg/m3):';
            app.text26 = uilabel(app.ImportData_uipanel);
            app.text26.Text = 'Crust avg. density (kg/m3):';
            app.text25 = uilabel(app.ImportData_uipanel);
            app.text25.Text = 'Sea water density (kg/m3):';
            app.Rhom_val = uieditfield(app.ImportData_uipanel, 'text');
            app.Rhom_val.Value = '3300.';
            app.Rhoc_val = uieditfield(app.ImportData_uipanel, 'text');
            app.Rhoc_val.Value = '2800.';
            app.Rhow_val = uieditfield(app.ImportData_uipanel, 'text');
            app.Rhow_val.Value = '1030.';
            app.CLOSEButton = uibutton(app.BACKTRACKINGTab, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.PLOTSAVERESULTSTab = uitab(app.TabGroup2);
            app.PLOTSAVERESULTSTab.Title = 'PLOT & SAVE RESULTS';
            app.Plot_results_uipanel = uipanel(app.PLOTSAVERESULTSTab);
            app.Plot_uipanel = uipanel(app.Plot_results_uipanel);
            app.Plot_uipanel.Title = 'OUTPUTS';
            app.TpushbuttonPlotResults = uibutton(app.Plot_uipanel, 'push');
            app.TpushbuttonPlotResults.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonPlotResults_Callback, true);
            app.TpushbuttonPlotResults.Enable = 'off';
            app.TpushbuttonPlotResults.Text = 'Plot';
            app.TpushbuttonContour = uibutton(app.Plot_uipanel, 'push');
            app.TpushbuttonContour.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonContour_Callback, true);
            app.TpushbuttonContour.Enable = 'off';
            app.TpushbuttonContour.Text = 'Contours (maps)';
            app.text_plotvar = uilabel(app.Plot_uipanel);
            app.text_plotvar.Text = 'Plot variables:';
            app.TpushbuttonSaveResults = uibutton(app.Plot_uipanel, 'push');
            app.TpushbuttonSaveResults.ButtonPushedFcn = createCallbackFcn(app, @TpushbuttonSaveResults_Callback, true);
            app.TpushbuttonSaveResults.Enable = 'off';
            app.TpushbuttonSaveResults.Text = 'Save ';
            app.text_savevar = uilabel(app.Plot_uipanel);
            app.text_savevar.Text = 'Save variables:';
            app.plotresults = uidropdown(app.Plot_uipanel);
            app.plotresults.Items = {'Select a variable', 'Horizons depths (m)', 'Isopachs (m)', 'Density (kg/cm3)', 'Porosity (%)', 'Thermal subsidence (m)', 'Isostasy (m)', 'Tectonic (m)'};
            app.plotresults.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.plotresults.ValueChangedFcn = createCallbackFcn(app, @PlotResults_menu, true);
            app.plotresults.Value = '1';
            app.maps2D3D_selection = uibuttongroup(app.Plot_uipanel);
            app.maps2D3D_selection.SelectionChangedFcn = createCallbackFcn(app, @Plot2D3DSelection, true);
            app.maps2D3D_selection.Title = 'Map view:';
            app.Button_2Dmaps = uiradiobutton(app.maps2D3D_selection);
            app.Button_2Dmaps.Text = '2D flat maps';
            app.Button_2Dmaps.Value = true;
            app.Button_3Dmaps = uiradiobutton(app.maps2D3D_selection);
            app.Button_3Dmaps.Text = 'real 3D maps';
            app.saveresults = uidropdown(app.Plot_uipanel);
            app.saveresults.Items = {'Select a variable', 'Horizons depths (m)', 'Isopachs (m)', 'Density (kg/m3)', 'Porosity (%)', 'Thermal subsidence (m)', 'Isostasy (m)', 'Tectonic (m)'};
            app.saveresults.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.saveresults.ValueChangedFcn = createCallbackFcn(app, @SaveResults_menu, true);
            app.saveresults.Value = '1';
            app.MinEditFieldLabel = uilabel(app.Plot_uipanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.Plot_uipanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.Plot_uipanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.Plot_uipanel, 'numeric');
            app.ColorbarButtonGroup = uibuttongroup(app.Plot_uipanel);
            app.ColorbarButtonGroup.Title = 'Colorbar:';
            app.AutomaticlevelsButton = uiradiobutton(app.ColorbarButtonGroup);
            app.AutomaticlevelsButton.Text = 'Automatic levels';
            app.AutomaticlevelsButton.Value = true;
            app.CustomlevelsButton = uiradiobutton(app.ColorbarButtonGroup);
            app.CustomlevelsButton.Text = 'Custom levels';
            app.PaletteLabel = uilabel(app.Plot_uipanel);
            app.PaletteLabel.Text = 'Palette:';
            app.PaletteMenu2 = uidropdown(app.Plot_uipanel);
            app.PaletteMenu2.Items = {'hsv', 'parula', 'jet', 'bam', 'broc', 'cork', 'roma', 'vik', 'hawai', 'lisbon', 'tofino', 'oleron'};
            app.PaletteMenu2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.PaletteMenu2.ValueChangedFcn = createCallbackFcn(app, @Palette_menu2, true);
            app.PaletteMenu2.Value = '1';
            app.FlipUD2_Button = uibuttongroup(app.Plot_uipanel);
            app.FlipUD2_Button.SelectionChangedFcn = createCallbackFcn(app, @FlipUD2_RadioButton, true);
            app.FlipUD2_No = uiradiobutton(app.FlipUD2_Button);
            app.FlipUD2_No.Text = 'No';
            app.FlipUD2_No.Value = true;
            app.FlipUD2_Yes = uiradiobutton(app.FlipUD2_Button);
            app.FlipUD2_Yes.Text = 'Yes';
            app.FlipUDLabel_2 = uilabel(app.Plot_uipanel);
            app.FlipUDLabel_2.Text = 'Flip UD:';
            app.Extract_profile_uipanel = uipanel(app.Plot_results_uipanel);
            app.Extract_profile_uipanel.Title = 'EXTRACT profile or well';
            app.Extract_coord_Selection = uibuttongroup(app.Extract_profile_uipanel);
            app.Extract_coord_Selection.Title = 'Select coordinates';
            app.radiobutton_coord_file = uiradiobutton(app.Extract_coord_Selection);
            app.radiobutton_coord_file.Text = 'Import coordinates from file';
            app.radiobutton_coord_file.Value = true;
            app.radiobutton_coord_table = uiradiobutton(app.Extract_coord_Selection);
            app.radiobutton_coord_table.Text = 'Import coordinates from Table';
            app.pushbutton_load_profile = uibutton(app.Extract_coord_Selection, 'push');
            app.pushbutton_load_profile.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_profile_Callback, true);
            app.pushbutton_load_profile.Text = 'Load profile';
            app.TuitablePoints = uitable(app.Extract_coord_Selection);
            app.TuitablePoints.ColumnName = {'X'; 'Y'};
            app.TuitablePoints.RowName = {'0'; '1'};
            app.TuitablePoints.ColumnEditable = [true true];
            app.text47 = uilabel(app.Extract_coord_Selection);
            app.text47.Text = '1D well or / 2D line: start-end points (in meters)';
            app.text_extract = uilabel(app.Extract_profile_uipanel);
            app.text_extract.Text = 'Extract  & Save variables:';
            app.Tpushbutton_Extract = uibutton(app.Extract_profile_uipanel, 'push');
            app.Tpushbutton_Extract.ButtonPushedFcn = createCallbackFcn(app, @Tpushbutton_ExtractButton, true);
            app.Tpushbutton_Extract.Enable = 'off';
            app.Tpushbutton_Extract.Text = 'Extract';
            app.Tpushbutton_Plot_extracted = uibutton(app.Extract_profile_uipanel, 'push');
            app.Tpushbutton_Plot_extracted.ButtonPushedFcn = createCallbackFcn(app, @Tpushbutton_Plot_Extracted, true);
            app.Tpushbutton_Plot_extracted.Enable = 'off';
            app.Tpushbutton_Plot_extracted.Text = 'Plot';
            app.edit_profile_name = uieditfield(app.Extract_profile_uipanel, 'text');
            app.edit_profile_name.Value = 'Insert profile name';
            app.Tpushbutton_save_extracted = uibutton(app.Extract_profile_uipanel, 'push');
            app.Tpushbutton_save_extracted.ButtonPushedFcn = createCallbackFcn(app, @Tpushbutton_Save_Extracted, true);
            app.Tpushbutton_save_extracted.Enable = 'off';
            app.Tpushbutton_save_extracted.Text = 'Save extracted data';
            app.ExtractVar = uidropdown(app.Extract_profile_uipanel);
            app.ExtractVar.Items = {'Select a variable', 'Horizons depths', 'Isopach', 'Density', 'Porosity', 'Thermal subsidence', 'Isostasy', 'Beta Factor', 'Tectonic'};
            app.ExtractVar.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ExtractVar.ValueChangedFcn = createCallbackFcn(app, @Extract_menu, true);
            app.ExtractVar.Value = '1';
            app.INITIALDATAPanel = uipanel(app.Plot_results_uipanel);
            app.INITIALDATAPanel.Title = 'INITIAL DATA';
            app.InitialData = uidropdown(app.INITIALDATAPanel);
            app.InitialData.Items = {'Select a variable', 'Horizon depths (m)', 'Elastic Thickness (m)', 'Sea Level changes (m)', 'Beta Factor', 'Dynamic topography (m)'};
            app.InitialData.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.InitialData.Value = '1';
            app.PlotvariablesLabel = uilabel(app.INITIALDATAPanel);
            app.PlotvariablesLabel.Text = 'Plot variables:';
            app.PlotButton = uibutton(app.INITIALDATAPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Tpushbuttom_PlotInit, true);
            app.PlotButton.Text = 'Plot';
            app.SavevariablesLabel = uilabel(app.INITIALDATAPanel);
            app.SavevariablesLabel.Text = 'Save variables:';
            app.SaveInitButton = uibutton(app.INITIALDATAPanel, 'push');
            app.SaveInitButton.ButtonPushedFcn = createCallbackFcn(app, @Tpushbutton_SaveInit, true);
            app.SaveInitButton.Text = 'Save';
            app.MapviewButtonGroup = uibuttongroup(app.INITIALDATAPanel);
            app.MapviewButtonGroup.Title = 'Map view:';
            app.plainmaps2DButton = uiradiobutton(app.MapviewButtonGroup);
            app.plainmaps2DButton.Text = '2D plain maps';
            app.plainmaps2DButton.Value = true;
            app.real3DmapsButton = uiradiobutton(app.MapviewButtonGroup);
            app.real3DmapsButton.Text = 'real 3D maps';
            app.ColorbarButtonGroup_2 = uibuttongroup(app.INITIALDATAPanel);
            app.ColorbarButtonGroup_2.Title = 'Colorbar:';
            app.AutomaticlevelsButton_2 = uiradiobutton(app.ColorbarButtonGroup_2);
            app.AutomaticlevelsButton_2.Text = 'Automatic levels';
            app.AutomaticlevelsButton_2.Value = true;
            app.CustomlevelsButton_2 = uiradiobutton(app.ColorbarButtonGroup_2);
            app.CustomlevelsButton_2.Text = 'Custom levels';
            app.MinEditField_2Label = uilabel(app.INITIALDATAPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.INITIALDATAPanel, 'numeric');
            app.MaxEditField_2Label = uilabel(app.INITIALDATAPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.INITIALDATAPanel, 'numeric');
            app.PaletteLabel_2 = uilabel(app.INITIALDATAPanel);
            app.PaletteLabel_2.Text = 'Palette:';
            app.PaletteMenu = uidropdown(app.INITIALDATAPanel);
            app.PaletteMenu.Items = {'hsv', 'parula', 'jet', 'bam', 'broc', 'cork', 'roma', 'vik', 'hawai', 'lisbon', 'tofino', 'oleron'};
            app.PaletteMenu.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.PaletteMenu.ValueChangedFcn = createCallbackFcn(app, @Palette_menu, true);
            app.PaletteMenu.Value = '1';
            app.FlipUD_Button = uibuttongroup(app.INITIALDATAPanel);
            app.FlipUD_Button.SelectionChangedFcn = createCallbackFcn(app, @FlipUD_RadioButton, true);
            app.FlipUD_No = uiradiobutton(app.FlipUD_Button);
            app.FlipUD_No.Text = 'No';
            app.FlipUD_No.Value = true;
            app.FlipUD_Yes = uiradiobutton(app.FlipUD_Button);
            app.FlipUD_Yes.Text = 'Yes';
            app.FlipUDLabel = uilabel(app.INITIALDATAPanel);
            app.FlipUDLabel.Text = 'Flip UD:';
            app.Image = uiimage(app.PALEOSTRIP);
            app.Image.ImageSource = 'OGS_transparent.png';
            % Show the figure after all components are created
            app.PALEOSTRIP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PALEOSTRIPv1_0_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PALEOSTRIP)

            % Execute the startup function
            runStartupFcn(app, @PALEOSTRIP_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PALEOSTRIP)
        end
    end
end